classdef ice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputPerformanceParametersLabel  matlab.ui.control.Label
        InternalCombustionEngineAnalyzerLabel  matlab.ui.control.Label
        InputPerformanceParametersLabel  matlab.ui.control.Label
        InputDesignParametersLabel      matlab.ui.control.Label
        W_pEditField                    matlab.ui.control.NumericEditField
        W_pEditFieldLabel               matlab.ui.control.Label
        W_igEditField                   matlab.ui.control.NumericEditField
        W_igEditFieldLabel              matlab.ui.control.Label
        W_inEditField                   matlab.ui.control.NumericEditField
        W_inEditFieldLabel              matlab.ui.control.Label
        NumberofCylindersEditField      matlab.ui.control.NumericEditField
        NumberofCylindersEditFieldLabel  matlab.ui.control.Label
        BrakeTorqueNmEditField          matlab.ui.control.NumericEditField
        BrakeTorqueNmEditFieldLabel     matlab.ui.control.Label
        EngineSpeedRPMEditField         matlab.ui.control.NumericEditField
        EngineSpeedRPMEditFieldLabel    matlab.ui.control.Label
        MechanicalEfficiencyEditField   matlab.ui.control.NumericEditField
        MechanicalEfficiencyLabel       matlab.ui.control.Label
        FMEPbarEditField                matlab.ui.control.NumericEditField
        FMEPbarEditFieldLabel           matlab.ui.control.Label
        BMEPbarEditField                matlab.ui.control.NumericEditField
        BMEPbarEditFieldLabel           matlab.ui.control.Label
        PMEPbarEditField                matlab.ui.control.NumericEditField
        PMEPbarEditFieldLabel           matlab.ui.control.Label
        IMEP_gbarEditField              matlab.ui.control.NumericEditField
        IMEP_gbarEditFieldLabel         matlab.ui.control.Label
        IMEP_nbarEditField              matlab.ui.control.NumericEditField
        IMEP_nbarEditFieldLabel         matlab.ui.control.Label
        DisplacedVolumem3EditField      matlab.ui.control.NumericEditField
        DisplacedVolumem3Label          matlab.ui.control.Label
        ConnnectingRodLengthmEditField  matlab.ui.control.NumericEditField
        ConnnectingRodLengthLabel       matlab.ui.control.Label
        StrokemEditField                matlab.ui.control.NumericEditField
        StrokemEditFieldLabel           matlab.ui.control.Label
        BoremEditField                  matlab.ui.control.NumericEditField
        BoremEditFieldLabel             matlab.ui.control.Label
        CompressionRatioEditField       matlab.ui.control.NumericEditField
        CompressionRatioLabel           matlab.ui.control.Label
        EVOEditField                    matlab.ui.control.NumericEditField
        EVOEditFieldLabel               matlab.ui.control.Label
        IVCEditField                    matlab.ui.control.NumericEditField
        IVCEditFieldLabel               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        BrowseFileButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            warning off;
            % Browses for excel file
            [filename,path] = uigetfile(".xlsx");
            % Reads excel file into a table
            t=readtable(filename);
            app.UITable.Data=t;
            % Split up columns in excel and identify them
            theta=t{:,1};
            P=t{:,2};

            % Define variables and calculate for input parameters
            rc=app.CompressionRatioEditField.Value;
            B=app.BoremEditField.Value;
            L=app.StrokemEditField.Value;
            l=app.ConnnectingRodLengthmEditField.Value;
            a=L/2;
            R=l/a;
            Vd=app.DisplacedVolumem3EditField.Value;
            Vc=Vd/(rc-1);

            % Initialize counter variables
            counter1 = 1;
            counter2 = 1;
            counter3 = 1;
            counter4 = 1;

            % Loop through crank angle column to identify the four strokes
            % of the engine's combustion cycle and convert the crank angle
            % to volume
            for n = 1:length(theta)
                if theta(n,1)<=app.IVCEditField.Value
                    IntakeV(counter1,1) = Vc*(1+0.5*(rc-1)*(R+1-cosd(theta(n,1))-(R^2-sind(theta(n,1))^2)^0.5));
                    counter1 = counter1 + 1;
                elseif app.IVCEditField.Value<theta(n,1) && theta(n,1)<=0
                    CompressionV(counter2,1) = Vc*(1+0.5*(rc-1)*(R+1-cosd(theta(n,1))-(R^2-sind(theta(n,1))^2)^0.5));
                    counter2 = counter2 + 1;
                elseif 0<theta(n,1) && theta(n,1)<=(app.EVOEditField.Value-360)
                    CombustionV(counter3,1) = Vc*(1+0.5*(rc-1)*(R+1-cosd(theta(n,1))-(R^2-sind(theta(n,1))^2)^0.5));
                    counter3 = counter3 + 1;
                else
                    ExhaustV(counter4,1) = Vc*(1+0.5*(rc-1)*(R+1-cosd(theta(n,1))-(R^2-sind(theta(n,1))^2)^0.5));
                    counter4 = counter4 + 1;
                end
            end

            % Plot scatter plot of all 4 strokes onto the same plot
            scatter(app.UIAxes,[IntakeV(2:end),CompressionV,CombustionV,ExhaustV],...
                [P(2:counter1-1),P(counter1:counter1+counter2-2),...
                P(counter1+counter2-1:counter1+counter2+counter3-3),...
                P(counter1+counter2+counter3-2:length(P))],2,"filled");
            legend(app.UIAxes,'Intake','Compression','Combustion','Exhaust');

            % Calculate area under each curve using trapezoidal rule
            areaintake = trapz(IntakeV(2:end),P(2:counter1-1));
            areacompression = trapz(CompressionV,P(counter1:counter1+counter2-2));
            areaexpansion = trapz(CombustionV,P(counter1+counter2-1:counter1+counter2+counter3-3));
            areaexhaust = trapz(ExhaustV,P(counter1+counter2+counter3-2:length(P)));

            % Calculate for gross indicated work per cycle W_ig, pumping 
            % work per cycle W_p, and net indicated work per cycle W_in, 
            W_ig = areaexpansion-areacompression; %units:bar*m^3
            W_p = abs(areaexhaust-areaintake);
            W_in = W_ig - W_p;

            % Output work values
            app.W_inEditField.Value = W_in;
            app.W_igEditField.Value = W_ig;
            app.W_pEditField.Value = W_p;

            % Calculate and output Net Indicated Mean Effective Pressure 
            % IMEPn, Gross Indicated Mean Effective Pressure IMEPg, and 
            % Pumping Mean Effective Pressure PMEP
            app.IMEP_nbarEditField.Value = W_in/Vd;
            app.IMEP_gbarEditField.Value = W_ig/Vd;
            app.PMEPbarEditField.Value = W_p/Vd;

            % Calculate for power values, Brake Mean Effective Pressure
            % BMEP, and Friction Mean Effective Pressure FMEP
            n_R = 2; %the number of crank revs per engine cycle for 4 stroke engine is 2
            N = app.EngineSpeedRPMEditField.Value/60;
            W_R = 2*pi()*app.BrakeTorqueNmEditField.Value; %N-m/cycle
            W_b = W_R*n_R;
            app.BMEPbarEditField.Value = (W_b*10^(-5))/Vd;
            app.FMEPbarEditField.Value = app.IMEP_nbarEditField.Value-app.BMEPbarEditField.Value;
            P_b = W_b*N/n_R; % engine
            NE = app.NumberofCylindersEditField.Value;
            P_ig = W_ig*N*10^5/n_R; % per cylinder
            P_in = W_in*N*10^5/n_R; % per cylinder

            % Calculate for mechanical efficiency
            app.MechanicalEfficiencyEditField.Value = P_b/P_in*100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'P-v Diagram')
            xlabel(app.UIAxes, 'V (m^3)')
            ylabel(app.UIAxes, 'P (bar)')
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Crank Angle (deg)'; 'Pressure (bar)'};
            app.UITable.RowName = {};
            app.IVCEditFieldLabel = uilabel(app.UIFigure);
            app.IVCEditFieldLabel.Text = 'IVC';
            app.IVCEditField = uieditfield(app.UIFigure, 'numeric');
            app.IVCEditField.Value = -180;
            app.EVOEditFieldLabel = uilabel(app.UIFigure);
            app.EVOEditFieldLabel.Text = 'EVO';
            app.EVOEditField = uieditfield(app.UIFigure, 'numeric');
            app.EVOEditField.Value = 540;
            app.CompressionRatioLabel = uilabel(app.UIFigure);
            app.CompressionRatioLabel.Text = 'Compression Ratio';
            app.CompressionRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.CompressionRatioEditField.Value = 18;
            app.BoremEditFieldLabel = uilabel(app.UIFigure);
            app.BoremEditFieldLabel.Text = 'Bore (m)';
            app.BoremEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoremEditField.Value = 0.095;
            app.StrokemEditFieldLabel = uilabel(app.UIFigure);
            app.StrokemEditFieldLabel.Text = 'Stroke (m)';
            app.StrokemEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrokemEditField.Value = 0.105;
            app.ConnnectingRodLengthLabel = uilabel(app.UIFigure);
            app.ConnnectingRodLengthLabel.Text = {'Connnecting'; 'Rod Length (m)'};
            app.ConnnectingRodLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConnnectingRodLengthmEditField.Value = 0.285;
            app.DisplacedVolumem3Label = uilabel(app.UIFigure);
            app.DisplacedVolumem3Label.Text = 'Displaced Volume (m^3)';
            app.DisplacedVolumem3EditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplacedVolumem3EditField.Value = 0.005954;
            app.IMEP_nbarEditFieldLabel = uilabel(app.UIFigure);
            app.IMEP_nbarEditFieldLabel.Text = 'IMEP_n (bar)';
            app.IMEP_nbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.IMEP_gbarEditFieldLabel = uilabel(app.UIFigure);
            app.IMEP_gbarEditFieldLabel.Text = 'IMEP_g (bar)';
            app.IMEP_gbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.PMEPbarEditFieldLabel = uilabel(app.UIFigure);
            app.PMEPbarEditFieldLabel.Text = 'PMEP (bar)';
            app.PMEPbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.BMEPbarEditFieldLabel = uilabel(app.UIFigure);
            app.BMEPbarEditFieldLabel.Text = 'BMEP (bar)';
            app.BMEPbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.FMEPbarEditFieldLabel = uilabel(app.UIFigure);
            app.FMEPbarEditFieldLabel.Text = 'FMEP (bar)';
            app.FMEPbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.MechanicalEfficiencyLabel = uilabel(app.UIFigure);
            app.MechanicalEfficiencyLabel.Text = {'Mechanical'; 'Efficiency (%)'};
            app.MechanicalEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EngineSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.EngineSpeedRPMEditFieldLabel.Text = 'Engine Speed (RPM)';
            app.EngineSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.EngineSpeedRPMEditField.Value = 1200;
            app.BrakeTorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.BrakeTorqueNmEditFieldLabel.Text = 'Brake Torque (N-m)';
            app.BrakeTorqueNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrakeTorqueNmEditField.Value = 528;
            app.NumberofCylindersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCylindersEditFieldLabel.Text = 'Number of Cylinders';
            app.NumberofCylindersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCylindersEditField.Value = 8;
            app.W_inEditFieldLabel = uilabel(app.UIFigure);
            app.W_inEditFieldLabel.Text = 'W_in';
            app.W_inEditField = uieditfield(app.UIFigure, 'numeric');
            app.W_igEditFieldLabel = uilabel(app.UIFigure);
            app.W_igEditFieldLabel.Text = 'W_ig';
            app.W_igEditField = uieditfield(app.UIFigure, 'numeric');
            app.W_pEditFieldLabel = uilabel(app.UIFigure);
            app.W_pEditFieldLabel.Text = 'W_p';
            app.W_pEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputDesignParametersLabel = uilabel(app.UIFigure);
            app.InputDesignParametersLabel.Text = 'Input (Design Parameters):';
            app.InputPerformanceParametersLabel = uilabel(app.UIFigure);
            app.InputPerformanceParametersLabel.Text = 'Input (Performance Parameters):';
            app.InternalCombustionEngineAnalyzerLabel = uilabel(app.UIFigure);
            app.InternalCombustionEngineAnalyzerLabel.Text = 'Internal Combustion Engine Analyzer';
            app.OutputPerformanceParametersLabel = uilabel(app.UIFigure);
            app.OutputPerformanceParametersLabel.Text = 'Output (Performance Parameters):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end